classdef ImageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadImageMenu                matlab.ui.container.Menu
        SaveImageMenu_2              matlab.ui.container.Menu
        FunctionsMenu                matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        Histogram                    matlab.ui.control.UIAxes
        ImageSizeLabel               matlab.ui.control.Label
        ImageSize                    matlab.ui.control.EditField
        ImageMeanEditFieldLabel      matlab.ui.control.Label
        ImageMean                    matlab.ui.control.EditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        StandardDeviation            matlab.ui.control.EditField
        TabGroup                     matlab.ui.container.TabGroup
        GrayLevelsTab                matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes8                      matlab.ui.control.UIAxes
        ReduceGrayLevelButton        matlab.ui.control.Button
        ShrinkingTab                 matlab.ui.container.Tab
        UIAxes11                     matlab.ui.control.UIAxes
        FactorEditFieldLabel         matlab.ui.control.Label
        FactorEditField              matlab.ui.control.NumericEditField
        IntensityTransformationTab   matlab.ui.container.Tab
        UIAxes9                      matlab.ui.control.UIAxes
        UIAxes10                     matlab.ui.control.UIAxes
        FunctionsDropDownLabel       matlab.ui.control.Label
        FunctionsDropDown            matlab.ui.control.DropDown
        ParameterLabel               matlab.ui.control.Label
        Para1EditField               matlab.ui.control.NumericEditField
        Para2EditField               matlab.ui.control.NumericEditField
        Para3EditField               matlab.ui.control.NumericEditField
        Para4EditField               matlab.ui.control.NumericEditField
        SDFilteringTab               matlab.ui.container.Tab
        FilterTypeDropDownLabel      matlab.ui.control.Label
        FilterTypeDropDown           matlab.ui.control.DropDown
        SDFilterButton               matlab.ui.control.Button
        SmoothingPanel               matlab.ui.container.Panel
        AverageCheckBox              matlab.ui.control.CheckBox
        MedianCheckBox               matlab.ui.control.CheckBox
        GaussianCheckBox             matlab.ui.control.CheckBox
        ContraharmCheckBox           matlab.ui.control.CheckBox
        AverageMask1                 matlab.ui.control.NumericEditField
        AverageMask2                 matlab.ui.control.NumericEditField
        AverageMask3                 matlab.ui.control.NumericEditField
        MedianMask1                  matlab.ui.control.NumericEditField
        MedianMask2                  matlab.ui.control.NumericEditField
        MedianMask3                  matlab.ui.control.NumericEditField
        GaussianSigma1               matlab.ui.control.NumericEditField
        GaussianSigma2               matlab.ui.control.NumericEditField
        GaussianSigma3               matlab.ui.control.NumericEditField
        ContraharmMask1              matlab.ui.control.NumericEditField
        ContraharmMask2              matlab.ui.control.NumericEditField
        ContraharmMask3              matlab.ui.control.NumericEditField
        QEditFieldLabel              matlab.ui.control.Label
        ContraharmQ                  matlab.ui.control.NumericEditField
        MaskLabel                    matlab.ui.control.Label
        MaskLabel_2                  matlab.ui.control.Label
        MaskLabel_3                  matlab.ui.control.Label
        SigmaLabel                   matlab.ui.control.Label
        SharpeningPanel              matlab.ui.container.Panel
        LaplacianCheckBox            matlab.ui.control.CheckBox
        PrewittCheckBox              matlab.ui.control.CheckBox
        SobelCheckBox                matlab.ui.control.CheckBox
        LaplacianAlpha1              matlab.ui.control.NumericEditField
        LaplacianAlpha2              matlab.ui.control.NumericEditField
        LaplacianAlpha3              matlab.ui.control.NumericEditField
        AlphaLabel                   matlab.ui.control.Label
        UIAxes12                     matlab.ui.control.UIAxes
        UIAxes13                     matlab.ui.control.UIAxes
        UIAxes14                     matlab.ui.control.UIAxes
        UIAxes15                     matlab.ui.control.UIAxes
        FDFilteringTab               matlab.ui.container.Tab
        FilterTypeDropDown_2Label    matlab.ui.control.Label
        FilterTypeDropDown_2         matlab.ui.control.DropDown
        FDFilterButton               matlab.ui.control.Button
        SmoothingPanel_2             matlab.ui.container.Panel
        IdealLowPassCheckBox         matlab.ui.control.CheckBox
        GaussianLowPassCheckBox      matlab.ui.control.CheckBox
        ButterworthLowPassCheckBox   matlab.ui.control.CheckBox
        D0EditFieldLabel             matlab.ui.control.Label
        IdealLowPassD0               matlab.ui.control.NumericEditField
        D0EditField_2Label           matlab.ui.control.Label
        GaussianLowPassD0            matlab.ui.control.NumericEditField
        D0EditField_3Label           matlab.ui.control.Label
        ButterworthLowPassD0         matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        ButterworthLowPassn          matlab.ui.control.NumericEditField
        SharpeningPanel_2            matlab.ui.container.Panel
        IdealHighPassCheckBox        matlab.ui.control.CheckBox
        GaussianHighPassCheckBox     matlab.ui.control.CheckBox
        ButterworthHighPassCheckBox  matlab.ui.control.CheckBox
        D0EditField_4Label           matlab.ui.control.Label
        IdealHighPassD0              matlab.ui.control.NumericEditField
        D0EditField_5Label           matlab.ui.control.Label
        GaussianHighPassD0           matlab.ui.control.NumericEditField
        D0EditField_6Label           matlab.ui.control.Label
        ButterworthHighPassD0        matlab.ui.control.NumericEditField
        nEditField_2Label            matlab.ui.control.Label
        ButterworthHighPassn         matlab.ui.control.NumericEditField
        UIAxes16                     matlab.ui.control.UIAxes
        UIAxes16_2                   matlab.ui.control.UIAxes
        UIAxes16_3                   matlab.ui.control.UIAxes
        UIAxes16_4                   matlab.ui.control.UIAxes
        NoiseReductionTab            matlab.ui.container.Tab
        GrayRawImgAxis               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        GrayRawImage % Description
    end
    
    methods (Access = private)
        
        function results = LogParanumberChanged(numfld)
            v=numfld.EditField;
            f = mat2gray(app.GrayRawImage);
            g_1 = log2(1 + v*f)/log2(v+1);
            imshow(g_1,[0 1],'parent',app.UIAxes10);
        end
        
        function results = PowerLawTransform(app)
            
            f = mat2gray(app.GrayRawImage,[0 255]);
            C = 1;
            Gamma = app.Para1EditField.Value;
            g = C*(f.^Gamma);
            imshow(g,[0 1],'parent',app.UIAxes10);
            
        end
        
        function results = LogTransform(app)
            v=app.Para1EditField.Value;
            f = mat2gray(app.GrayRawImage);
            g = log2(1 + v*f)/log2(v+1);
            imshow(g,[0 1],'parent',app.UIAxes10);
        end
        
        function results = PieceWiseLinearTransform(app)
            f0=0;g0=0;
            f1=app.Para1EditField.Value; g1=app.Para2EditField.Value;
            f2=app.Para3EditField.Value; g2=app.Para4EditField.Value;
            f3=255;g3=255;
            plot(app.UIAxes9,[f0,f1,f2,f3],[g0,g1,g2,g3]); 
            
            r1=(g1-g0)/(f1-f0);
            b1=g0-r1*f0;
            r2=(g2-g1)/(f2-f1);
            b2=g1-r2*f1;
            r3=(g3-g2)/(f3-f2);
            b3=g2-r3*f2;
            [m,n]=size(app.GrayRawImage);
            X2=double(app.GrayRawImage);
            for i=1:m
                for j=1:n
                    f=X2(i,j);
                    g(i,j)=0;
                    if(f>=f1)&(f<=f2)
                        g(i,j)=r1*f+b2;
                    elseif(f>=f2)&(f<=f3)
                        g(i,j)=r3*f+b3;
                    end
                end
            end

            imshow(mat2gray(g),[0 1],'parent',app.UIAxes10);
            
        end
        
        function imt = ReduceGrayLevel(app,img, factor)
            if factor < 0
                factor = 0;
            elseif factor > 8
                factor = 8;    
            end
            
            dfact = uint8(power(2,factor));
            imt = (img / dfact) * dfact;
        end
        
        function imt = change_pixels(app,ima,factor)
            
            % 功能： 基于像素复制方式进行图像缩放
            % 输入参数为一整数缩放因子
            % 当缩放因子为负整数时代表缩小，正整数时代表放大
            f=round(factor);
            % wH=1; wL=2;
            [xs,ys] = size(ima);
            if f<0               %缩放因子小于0时，判断为缩小图像
                fa = abs(f);       %取绝对值
                %imt = zeros((xs/fa),(xs/fa));  
                for i=1:(ceil(xs/fa))
                    for j=1:(ceil(ys/fa))
                        imt(i,j) = ima(i*fa,j*fa);
                    end
                end
            elseif f>0           %缩放因子大于0时，判断为放大图像
                    for i=1:(xs*f)
                        for j=1:(ys*f)
                            imt(i,j) = ima(ceil(i/f),ceil(j/f));  
            % round(x)：四舍五入至最近整数  
            % fix(x)：无论正负，舍去小数至最近整数 
            % floor(x)：下取整，即舍去正小数至最近整数  
            % ceil(x)：上取整，即加入正小数至最近整数
                        end
                    end
            else 
                imt = ima;
            end
        end
        
        function reformedimage = ContraHarmFiltering(app, img, Q, masksize)
            data=im2double(img);
            
            pixln=0;
            pixld=0;
            [ro,col]=size(data);
            reformedimage = zeros(ro, col);
            %% Main Module for Contra Harmonic Mean Filter
            for i=1:ro
                for j=1:col
                    for m=-masksize:masksize
                        for n=-masksize:masksize
                            if (i+m>0 && i+m<ro && j+n>0 && j+n<col && ...      % To keep indices in limit
                                    masksize+m>0 && masksize+m<ro && ...
                                    masksize+n>0 && masksize+n<col) 
                                
                                pixl1=(data(i+m,j+n)).^(Q+1);                   % Application of Formula
                                pixl2= (data(i+m,j+n)).^Q;
                                pixln=pixln+pixl1;                              % Application of Summation
                                pixld=pixld+pixl2;
                            
                            end
                        end
                    end
                   reformedimage(i,j)=(pixln/pixld);
                   pixln=0;
                   pixld=0;
                end
            end            
        end
        
        function [SelectionNum, SelectedFunc] = CheckSDFilteringFuncSelection(app)
            SelectionNum = app.AverageCheckBox.Value + app.MedianCheckBox.Value + app.GaussianCheckBox.Value + app.ContraharmCheckBox.Value + ...
                           app.LaplacianCheckBox.Value + app.SobelCheckBox.Value + app.PrewittCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.AverageCheckBox.Value == 1)
                    SelectedFunc = 'Average';
                elseif (app.MedianCheckBox.Value == 1)
                    SelectedFunc = 'Median';
                elseif (app.GaussianCheckBox.Value == 1)
                    SelectedFunc = 'Gaussian';
                elseif (app.ContraharmCheckBox.Value == 1)
                    SelectedFunc = 'ContraHarm';
                elseif (app.LaplacianCheckBox.Value == 1)
                    SelectedFunc = 'Laplacian';
                elseif (app.SobelCheckBox.Value == 1)
                    SelectedFunc = 'Sobel';
                elseif (app.PrewittCheckBox.Value == 1)
                    SelectedFunc = 'Prewitt';
                end
            end
        end
        
        
        function [SelectionNum, SelectedFunc] = CheckFDFilteringFuncSelection(app)
            SelectionNum = app.IdealLowPassCheckBox.Value + app.GaussianLowPassCheckBox.Value + app.ButterworthLowPassCheckBox.Value + ...
                           app.IdealHighPassCheckBox.Value + app.GaussianHighPassCheckBox.Value + app.ButterworthHighPassCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.IdealLowPassCheckBox.Value == 1)
                    SelectedFunc = 'IdealLowPass';
                elseif (app.GaussianLowPassCheckBox.Value == 1)
                    SelectedFunc = 'GaussianLowPass';
                elseif (app.ButterworthLowPassCheckBox.Value == 1)
                    SelectedFunc = 'ButterworthLowPass';
                elseif (app.IdealHighPassCheckBox.Value == 1)
                    SelectedFunc = 'IdealHighPass';
                elseif (app.GaussianHighPassCheckBox.Value == 1)
                    SelectedFunc = 'GaussianHighPass';
                elseif (app.ButterworthHighPassCheckBox.Value == 1)
                    SelectedFunc = 'ButterworthHighPass';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            
            imgpath='original.jpg';
            %Read the image data
            rawimg=imread(imgpath);
            
            %Change to gray image and store it to public variable
            app.GrayRawImage = rgb2gray(rawimg);
            
            imshow(app.GrayRawImage,'parent',app.GrayRawImgAxis);
            
            histogram(app.Histogram, app.GrayRawImage);
            
            [row,column]=size(app.GrayRawImage);
            imgsize = strcat(int2str(row),'*',int2str(column));
            app.ImageSize.Value=imgsize;
            app.ImageMean.Value = num2str(mean2(app.GrayRawImage));
            app.StandardDeviation.Value=num2str(std2(app.GrayRawImage));
            app.ButterworthLowPassn.Enable=false;

        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
           %Select a image file 
            [filename,pathname]=uigetfile( ...
            {'*.jpg;*.png;*.bmp;*.gif;*.tif', ...
                'image files (*.jpg,*.png,*.bmp,*.gif,*.tif)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Please select an image.');
            if isequal(filename,0) || isequal(pathname,0)
                return;
            else
                imgpath=[pathname,filename];
            end
            %Create axes graphics object
            %Read the image data
            app.GrayRawImage=imread(imgpath);
            
            mysize=size(app.GrayRawImage);
            if numel(mysize)>2
                %Change to gray image and store it to public variable
                app.GrayRawImage = rgb2gray(app.GrayRawImage);
            end
            
            imshow(app.GrayRawImage,'parent',app.GrayRawImgAxis);

            
            histr = histogram(app.Histogram, app.GrayRawImage);
            
            [row,column]=size(app.GrayRawImage);
            imgsize = strcat(int2str(row),'*',int2str(column));
            app.ImageSize.Value=imgsize;
            app.ImageMean.Value = num2str(mean2(app.GrayRawImage));
            app.StandardDeviation.Value=num2str(std2(app.GrayRawImage));
            
        end

        % Button pushed function: ReduceGrayLevelButton
        function ReduceGrayLevelButtonPushed(app, event)

            imshow(ReduceGrayLevel(app,app.GrayRawImage,0),'parent',app.UIAxes);
            app.UIAxes.Title.String ='Original';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,1),'parent',app.UIAxes2);
            app.UIAxes2.Title.String ='128Level';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,2),'parent',app.UIAxes3);
            app.UIAxes3.Title.String ='64Level';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,3),'parent',app.UIAxes4);
            app.UIAxes4.Title.String ='32Level';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,4),'parent',app.UIAxes5);
            app.UIAxes5.Title.String ='16Level';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,5),'parent',app.UIAxes6);
            app.UIAxes6.Title.String ='8Level';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,6),'parent',app.UIAxes7);
            app.UIAxes7.Title.String ='4Level';
            imshow(ReduceGrayLevel(app,app.GrayRawImage,7),'parent',app.UIAxes8);
            app.UIAxes8.Title.String ='2Level';
            
            %subplot(2,4,1), imshow(app.GrayRawImage,[]);
            %subplot(2,4,2), imshow(app.GrayRawImage/2,[]);
            %subplot(2,4,3), imshow(app.GrayRawImage/4,[]);
            %subplot(2,4,4), imshow(app.GrayRawImage/8,[]);
            %subplot(2,4,5), imshow(app.GrayRawImage/16,[]);
            %subplot(2,4,6), imshow(app.GrayRawImage/32,[]);
            %subplot(2,4,7), imshow(app.GrayRawImage/64,[]);
            %subplot(2,4,8), imshow(app.GrayRawImage/128,[]);
            
        end

        % Value changed function: FunctionsDropDown
        function FunctionsDropDownValueChanged(app, event)
            value = app.FunctionsDropDown.Value;
           
            switch value
                case 'Negative'
                    app.Para1EditField.Visible = false;
                    app.Para2EditField.Visible = false;
                    app.Para3EditField.Visible = false;
                    app.Para4EditField.Visible = false;
                    
                    x=[0:1:255];
                    y= 255-x;
                    plot(app.UIAxes9,y);
                    
                    imshow(255-app.GrayRawImage,'parent',app.UIAxes10);
                    app.Para1EditField.Visible = false;
                    
                case 'Logrithm'
                    app.Para1EditField.Visible = true;
                    app.Para2EditField.Visible = false;
                    app.Para3EditField.Visible = false;
                    app.Para4EditField.Visible = false;
                    
                    app.Para1EditField.Value = 1;
                    
                    f = 0:0.01:1;
                    v1 = 1;
                    y1 = log2(1 + v1*f)/log2(v1+1); 
                    v2 = 10;
                    y2 = log2(1 + v2*f)/log2(v2+1); 
                    v3 = 50;
                    y3 = log2(1 + v3*f)/log2(v3+1); 
                    v4 = 100;
                    y4 = log2(1 + v4*f)/log2(v4+1); 
                    v5 = 200;
                    y5 = log2(1 + v5*f)/log2(v5+1); 
                    plot(app.UIAxes9,f,y1,'g',f,y2,'r',f,y3,'b',f,y4,'c',f,y5,'y');
                    
                    title(app.UIAxes9,'Log transformation: g = log(1+v*r)/log(1+v)');
            
                    lgd = legend(app.UIAxes9,'Log(v=1)','Log(v=10)','Log(v=50)','Log(v=100)','Log(v=200)');
                    legend(app.UIAxes9,'Location','southeast');
                    %title(app.UIAxes9,lgd,'The Legend Title')

                    LogTransform(app);
                    
                    
                case 'PowerLaw'
                    app.Para1EditField.Visible = true;
                    app.Para2EditField.Visible = false;
                    app.Para3EditField.Visible = false;
                    app.Para4EditField.Visible = false;
                    
                    app.Para1EditField.Value = 1;
                    
                    C = 1;
                    
                     f = 0:0.01:1;
                    Gamma = 0.1;
                    y1 = C*(f.^Gamma); 
                    Gamma = 0.2;
                    y2 =  C*(f.^Gamma); 
                    Gamma = 0.4;
                    y3 =  C*(f.^Gamma); 
                    Gamma = 2.5;
                    y4 =  C*(f.^Gamma); 
                    Gamma = 5;
                    y5 =  C*(f.^Gamma); 
                    Gamma = 20;
                    y6 =  C*(f.^Gamma); 
                    plot(app.UIAxes9,f,y1,'g',f,y2,'r',f,y3,'b',f,y4,'c',f,y5,'y',f,y6,'m');                   
                    
                    
                    PowerLawTransform(app);
                    
                case 'PiecewiseLinear'
                    app.Para1EditField.Visible = true;
                    app.Para2EditField.Visible = true;
                    app.Para3EditField.Visible = true;
                    app.Para4EditField.Visible = true;
                    
                    app.Para1EditField.Value = 50;
                    app.Para2EditField.Value = 30;
                    app.Para3EditField.Value = 170;
                    app.Para4EditField.Value = 220;
                    
                    PieceWiseLinearTransform(app);
                    
                case 'Histogram'
                    app.Para1EditField.Visible = false;
                    app.Para2EditField.Visible = false;
                    app.Para3EditField.Visible = false;
                    app.Para4EditField.Visible = false;
                    
                    g=histeq(app.GrayRawImage);
                    imshow(g,'parent',app.UIAxes9);
                    histogram(app.UIAxes10,g);

                    
                otherwise
                    
                   
            end
        end

        % Value changed function: Para1EditField
        function Para1EditFieldValueChanged(app, event)
        
            switch app.FunctionsDropDown.Value
                case 'Negative'
                    
                case 'Logrithm'
                    LogTransform(app)                
                case 'PowerLaw'
                    PowerLawTransform(app);
                   
                case 'PiecewiseLinear'
                    PieceWiseLinearTransform(app);
                case 'Histogram'
                    
                otherwise
                    
            end
                                     
        end

        % Value changed function: Para2EditField
        function Para2EditFieldValueChanged(app, event)
            switch app.FunctionsDropDown.Value
                case 'Negative'
                    
                case 'Logrithm'
               
                case 'PowerLaw'

                case 'PiecewiseLinear'
                    PieceWiseLinearTransform(app);
                case 'Histogram'
                    
                otherwise
                    
            end
            
        end

        % Value changed function: Para3EditField
        function Para3EditFieldValueChanged(app, event)
            switch app.FunctionsDropDown.Value
                case 'Negative'
                    
                case 'Logrithm'
               
                case 'PowerLaw'

                case 'PiecewiseLinear'
                    PieceWiseLinearTransform(app);
                case 'Histogram'
                    
                otherwise
                    
            end
            
        end

        % Value changed function: Para4EditField
        function Para4EditFieldValueChanged(app, event)
            switch app.FunctionsDropDown.Value
                case 'Negative'
                    
                case 'Logrithm'
               
                case 'PowerLaw'

                case 'PiecewiseLinear'
                    PieceWiseLinearTransform(app);
                case 'Histogram'
                    
                otherwise
                    
            end
            
        end

        % Value changed function: FactorEditField
        function FactorEditFieldValueChanged(app, event)
            value = app.FactorEditField.Value;
            sized_img = imresize(app.GrayRawImage,value,'nearest');
            imshow(sized_img, 'parent',app.UIAxes11);
            
            I2=imresize(app.GrayRawImage,2,'nearest');
            I3=imresize(app.GrayRawImage,0.5,'nearest');

            subplot(1,3,1), imshow(app.GrayRawImage,[]);
            subplot(1,3,2), imshow(I2,[]);
            subplot(1,3,3), imshow(I3,[]);

        end

        % Button pushed function: SDFilterButton
        function SDFilterButtonPushed(app, event)
            [SelectionNum, SelectedFunc] = CheckSDFilteringFuncSelection(app);
            
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'Average'
                        h1=fspecial('average',[app.AverageMask1.Value,app.AverageMask1.Value]);
                        h2=fspecial('average',[app.AverageMask2.Value,app.AverageMask2.Value]);
                        h3=fspecial('average',[app.AverageMask3.Value,app.AverageMask3.Value]);
                        g1=imfilter(app.GrayRawImage,h1);
                        g2=imfilter(app.GrayRawImage,h2);
                        g3=imfilter(app.GrayRawImage,h3);
                        
                        title1 = strcat(num2str(app.AverageMask1.Value),'X',num2str(app.AverageMask1.Value),' mean filter mask');
                        title2 = strcat(num2str(app.AverageMask2.Value),'X',num2str(app.AverageMask2.Value),' mean filter mask');
                        title3 = strcat(num2str(app.AverageMask3.Value),'X',num2str(app.AverageMask3.Value),' mean filter mask');
    
                    case 'Median'
                        g1 = medfilt2(app.GrayRawImage, [app.MedianMask1.Value,app.MedianMask1.Value]);
                        g2 = medfilt2(app.GrayRawImage, [app.MedianMask2.Value,app.MedianMask2.Value]);
                        g3 = medfilt2(app.GrayRawImage, [app.MedianMask3.Value,app.MedianMask3.Value]);
                        title1 = strcat(num2str(app.MedianMask1.Value),'X',num2str(app.MedianMask1.Value),' Median filter mask');
                        title2 = strcat(num2str(app.MedianMask2.Value),'X',num2str(app.MedianMask2.Value),' Median filter mask');
                        title3 = strcat(num2str(app.MedianMask3.Value),'X',num2str(app.MedianMask3.Value),' Median filter mask');
                        
                    case 'Gaussian'
                        
                        g1 = imgaussfilt(app.GrayRawImage,app.GaussianSigma1.Value);
                        g2 = imgaussfilt(app.GrayRawImage,app.GaussianSigma2.Value);
                        g3 = imgaussfilt(app.GrayRawImage,app.GaussianSigma3.Value);
                        title1 = strcat('Gaussian sigma ',num2str(app.GaussianSigma1.Value));
                        title2 = strcat('Gaussian sigma ',num2str(app.GaussianSigma2.Value));
                        title3 = strcat('Gaussian sigma ',num2str(app.GaussianSigma3.Value));
                       
                    case 'Laplacian'
                        h1=fspecial('laplacian',0.1);
                        h2=fspecial('laplacian',0.2);
                        h3=fspecial('laplacian',0.5);
                        g1=imfilter(app.GrayRawImage,h1);
                        g2=imfilter(app.GrayRawImage,h2);
                        g3=imfilter(app.GrayRawImage,h3);
                        title1 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha1.Value));
                        title2 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha2.Value));
                        title3 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha3.Value));
                   
                    case 'Sobel'
                        h1 = fspecial('sobel');
                        g1 = imfilter(app.GrayRawImage,h1);
                        g2 = [];
                        g3 = [];
                        title1 = 'Sobel filtering';
                        title2 = '';
                        title3 = '';
                        
                    case 'Prewitt'
                        h1 = fspecial('prewitt');
                        g1 = imfilter(app.GrayRawImage,h1);
                        g2 = [];
                        g3 = [];
                        title1 = 'Prewitt filtering';
                        title2 = '';
                        title3 = '';
                    
                    case 'ContraHarm'
                        
                        g1 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask1.Value);
                        g2 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask2.Value);
                        g3 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask3.Value);
                        title1 = strcat(num2str(app.ContraharmMask1.Value),'X',num2str(app.ContraharmMask1.Value),' ContraHarm filter mask');
                        title2 = strcat(num2str(app.ContraharmMask2.Value),'X',num2str(app.ContraharmMask2.Value),' ContraHarm filter mask');
                        title3 = strcat(num2str(app.ContraharmMask3.Value),'X',num2str(app.ContraharmMask3.Value),' ContraHarm filter mask');
                    otherwise
                        
                end
                
                %Display filtered images
                imshow(app.GrayRawImage,'parent',app.UIAxes12);
                app.UIAxes12.Title.String = 'Original';
                
                imshow(g1,'parent',app.UIAxes13);
                app.UIAxes13.Title.String = title1;
                if(size(g2) ~= 0)
                    imshow(g2,'parent',app.UIAxes14);
                else
                    cla(app.UIAxes14,'reset');
                end
                app.UIAxes14.Title.String = title2;
                if(size(g3) ~= 0)
                    imshow(g3,'parent',app.UIAxes15);
                else
                    cla(app.UIAxes15,'reset');
                end
                app.UIAxes15.Title.String = title3;
            elseif(SelectionNum > 1)
                img_index = 1;
                if(app.AverageCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1=fspecial('average',[app.AverageMask1.Value,app.AverageMask1.Value]);
                    h2=fspecial('average',[app.AverageMask2.Value,app.AverageMask2.Value]);
                    h3=fspecial('average',[app.AverageMask3.Value,app.AverageMask3.Value]);
                    g1=imfilter(app.GrayRawImage,h1);
                    g2=imfilter(app.GrayRawImage,h2);
                    g3=imfilter(app.GrayRawImage,h3);
                    
                    title1 = strcat(num2str(app.AverageMask1.Value),'X',num2str(app.AverageMask1.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    title2 = strcat(num2str(app.AverageMask2.Value),'X',num2str(app.AverageMask2.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;
                    
                    title3 = strcat(num2str(app.AverageMask3.Value),'X',num2str(app.AverageMask3.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.MedianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = medfilt2(app.GrayRawImage, [app.MedianMask1.Value,app.MedianMask1.Value]);
                    g2 = medfilt2(app.GrayRawImage, [app.MedianMask2.Value,app.MedianMask2.Value]);
                    g3 = medfilt2(app.GrayRawImage, [app.MedianMask3.Value,app.MedianMask3.Value]);
                    title1 = strcat(num2str(app.MedianMask1.Value),'X',num2str(app.MedianMask1.Value),' Median filter mask');
                    title2 = strcat(num2str(app.MedianMask2.Value),'X',num2str(app.MedianMask2.Value),' Median filter mask');
                    title3 = strcat(num2str(app.MedianMask3.Value),'X',num2str(app.MedianMask3.Value),' Median filter mask');

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.GaussianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = imgaussfilt(app.GrayRawImage,app.GaussianSigma1.Value);
                    g2 = imgaussfilt(app.GrayRawImage,app.GaussianSigma2.Value);
                    g3 = imgaussfilt(app.GrayRawImage,app.GaussianSigma3.Value);
                    title1 = strcat('Gaussian sigma ',num2str(app.GaussianSigma1.Value));
                    title2 = strcat('Gaussian sigma ',num2str(app.GaussianSigma2.Value));
                    title3 = strcat('Gaussian sigma ',num2str(app.GaussianSigma3.Value));

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.LaplacianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1=fspecial('laplacian',0.1);
                    h2=fspecial('laplacian',0.2);
                    h3=fspecial('laplacian',0.5);
                    g1=imfilter(app.GrayRawImage,h1);
                    g2=imfilter(app.GrayRawImage,h2);
                    g3=imfilter(app.GrayRawImage,h3);
                    title1 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha1.Value));
                    title2 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha2.Value));
                    title3 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha3.Value));

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.SobelCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1 = fspecial('sobel');
                    g1 = imfilter(app.GrayRawImage,h1);
                    g2 = [];
                    g3 = [];
                    title1 = 'Sobel filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.PrewittCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1 = fspecial('prewitt');
                    g1 = imfilter(app.GrayRawImage,h1);
                    g2 = [];
                    g3 = [];
                    title1 = 'Prewitt filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.ContraharmCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask1.Value);
                    g2 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask2.Value);
                    g3 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask3.Value);
                    title1 = strcat(num2str(app.ContraharmMask1.Value),'X',num2str(app.ContraharmMask1.Value),' ContraHarm filter mask');
                    title2 = strcat(num2str(app.ContraharmMask2.Value),'X',num2str(app.ContraharmMask2.Value),' ContraHarm filter mask');
                    title3 = strcat(num2str(app.ContraharmMask3.Value),'X',num2str(app.ContraharmMask3.Value),' ContraHarm filter mask');

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
            end
                
        end

        % Button pushed function: FDFilterButton
        function FDFilterButtonPushed(app, event)
            f=im2double(app.GrayRawImage);
            f=im2double(f);
            
            %计算填充图像大小
            [M,N]=size(f);
            P=2*M;
            Q=2*N;
            
            %傅里叶变换
            F=fftshift(fft2(f,P,Q));
            fft_img=mat2gray(log(1+abs(F)));
            
            %生成网格坐标
            u = -N:N-1;
            v = -M:M-1;
            [U,V] = meshgrid(u,v);
            
            %设计滤波器
            D = hypot(U,V);
            D0 = app.IdealLowPassD0.Value; %截止频率
            n = app.ButterworthLowPassn.Value; %巴特沃斯滤波器阶数
            
            [SelectionNum, SelectedFunc] = CheckFDFilteringFuncSelection(app);
            
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'IdealLowPass'
                        H = mat2gray(D <= D0); %理想低通滤波器
                        title_H = 'IdealLowPassFilter';
                        title_g = 'After IdealLowPassFiltering';
                    case 'GaussianLowPass'
                        H = exp((-D.^2)/(2*(D0).^2));%高斯低通滤波器
                        title_H = 'GaussianLowPassFilter';
                        title_g = 'After GaussianLowPassFiltering';
     
                    case 'ButterworthLowPass'
                        H = mat2gray(1./(1+((D./D0).^(2*n)))); %巴特沃斯低通滤波器
                        title_H = 'ButterworthLowPassFilter';
                        title_g = 'After ButterworthLowPassFiltering';
                        
                    case 'IdealHighPass'
                        H = mat2gray(D > D0); %理想高通滤波器
                        title_H = 'IdealHighPassFilter';
                        title_g = 'After IdealHighPassFiltering';
                        
                    case 'GaussianHighPass'
                        H = 1-exp((-D.^2)/(2*(D0).^2));%高斯高通滤波器
                        title_H = 'GaussianHighPassFilter';
                        title_g = 'After GaussianHighPassFiltering';
                        
                    case 'ButterworthHighPass'
                        H = 1./(1+((D0./D).^(2*n)));%布特沃斯高通滤波器
                        title_H = 'ButterworthHighPassFilter';
                        title_g = 'After ButterworthHighPassFiltering';
                    otherwise
                        
                end
                %频域滤波
                G = F.*H;
                g0 = ifft2(fftshift(G));
                g = g0(1:M,1:N);
                g = real(g);
                
                imshow(f,'parent',app.UIAxes16);
                app.UIAxes16.Title.String = 'original';
                
                imshow(fft_img,'parent',app.UIAxes16_2);
                app.UIAxes16_2.Title.String = 'FFT';
                
                imshow(H,'parent',app.UIAxes16_3);
                app.UIAxes16_3.Title.String = title_H;
                
                imshow(g,'parent',app.UIAxes16_4);
                app.UIAxes16_4.Title.String = title_g;
            elseif (SelectionNum > 1)
                img_index = 1;
                if(app.IdealLowPassCheckBox.Value == 1)
                    H = mat2gray(D <= D0); %理想低通滤波器
                    title_H = 'IdealLowPassFilter';
                    title_g = 'After IdealLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.GaussianLowPassCheckBox.Value == 1)
                    H = exp((-D.^2)/(2*(D0).^2));%高斯低通滤波器
                    title_H = 'GaussianLowPassFilter';
                    title_g = 'After GaussianLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthLowPassCheckBox.Value == 1)
                    H = mat2gray(1./(1+((D./D0).^(2*n)))); %巴特沃斯低通滤波器
                    title_H = 'ButterworthLowPassFilter';
                    title_g = 'After ButterworthLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.IdealHighPassCheckBox.Value == 1)
                    H = mat2gray(D > D0); %理想高通滤波器
                    title_H = 'IdealHighPassFilter';
                    title_g = 'After IdealHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.GaussianHighPassCheckBox.Value == 1)
                    H = 1-exp((-D.^2)/(2*(D0).^2));%高斯高通滤波器
                    title_H = 'GaussianHighPassFilter';
                    title_g = 'After GaussianHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthHighPassCheckBox == 1)
                    H = 1./(1+((D0./D).^(2*n)));%布特沃斯高通滤波器
                    title_H = 'ButterworthHighPassFilter';
                    title_g = 'After ButterworthHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'LoadImage';
            app.SaveImageMenu_2 = uimenu(app.FileMenu);
            app.SaveImageMenu_2.Text = 'SaveImage';
            app.FunctionsMenu = uimenu(app.UIFigure);
            app.FunctionsMenu.Text = 'Functions';
            app.Menu = uimenu(app.FunctionsMenu);
            app.Menu.Text = 'Menu';
            app.Histogram = uiaxes(app.UIFigure);
            title(app.Histogram, 'Histogram')
            xlabel(app.Histogram, '')
            ylabel(app.Histogram, '')
            app.ImageSizeLabel = uilabel(app.UIFigure);
            app.ImageSizeLabel.Text = 'ImageSize';
            app.ImageSize = uieditfield(app.UIFigure, 'text');
            app.ImageMeanEditFieldLabel = uilabel(app.UIFigure);
            app.ImageMeanEditFieldLabel.Text = 'ImageMean';
            app.ImageMean = uieditfield(app.UIFigure, 'text');
            app.StandardDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviation = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrayLevelsTab = uitab(app.TabGroup);
            app.GrayLevelsTab.Title = 'Gray Levels';
            app.Panel = uipanel(app.GrayLevelsTab);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes4 = uiaxes(app.Panel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes5 = uiaxes(app.Panel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes6 = uiaxes(app.Panel);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.YTick = [0 0.5 1];
            app.UIAxes7 = uiaxes(app.Panel);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes8 = uiaxes(app.Panel);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.ReduceGrayLevelButton = uibutton(app.GrayLevelsTab, 'push');
            app.ReduceGrayLevelButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceGrayLevelButtonPushed, true);
            app.ReduceGrayLevelButton.Text = 'Reduce Gray Level';
            app.ShrinkingTab = uitab(app.TabGroup);
            app.ShrinkingTab.Title = 'Shrinking';
            app.UIAxes11 = uiaxes(app.ShrinkingTab);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            app.FactorEditFieldLabel = uilabel(app.ShrinkingTab);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.ShrinkingTab, 'numeric');
            app.FactorEditField.ValueChangedFcn = createCallbackFcn(app, @FactorEditFieldValueChanged, true);
            app.IntensityTransformationTab = uitab(app.TabGroup);
            app.IntensityTransformationTab.Title = 'Intensity Transformation';
            app.UIAxes9 = uiaxes(app.IntensityTransformationTab);
            title(app.UIAxes9, 'Transformation')
            xlabel(app.UIAxes9, 'r')
            ylabel(app.UIAxes9, 's=T(r)')
            app.UIAxes9.XTick = [0 1 255];
            app.UIAxes9.XTickLabel = {'0'; '255'};
            app.UIAxes9.YTick = [0 1 255];
            app.UIAxes9.YTickLabel = {'0'; '255'};
            app.UIAxes10 = uiaxes(app.IntensityTransformationTab);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.FunctionsDropDownLabel = uilabel(app.IntensityTransformationTab);
            app.FunctionsDropDownLabel.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.IntensityTransformationTab);
            app.FunctionsDropDown.Items = {'Negative', 'Logrithm', 'PowerLaw', 'Piecewise-linear', 'Histogram'};
            app.FunctionsDropDown.ItemsData = {'Negative', 'Logrithm', 'PowerLaw', 'PiecewiseLinear', 'Histogram'};
            app.FunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionsDropDownValueChanged, true);
            app.FunctionsDropDown.Value = 'Negative';
            app.ParameterLabel = uilabel(app.IntensityTransformationTab);
            app.ParameterLabel.Text = 'Parameter';
            app.Para1EditField = uieditfield(app.IntensityTransformationTab, 'numeric');
            app.Para1EditField.ValueChangedFcn = createCallbackFcn(app, @Para1EditFieldValueChanged, true);
            app.Para1EditField.Value = 1;
            app.Para2EditField = uieditfield(app.IntensityTransformationTab, 'numeric');
            app.Para2EditField.ValueChangedFcn = createCallbackFcn(app, @Para2EditFieldValueChanged, true);
            app.Para3EditField = uieditfield(app.IntensityTransformationTab, 'numeric');
            app.Para3EditField.ValueChangedFcn = createCallbackFcn(app, @Para3EditFieldValueChanged, true);
            app.Para4EditField = uieditfield(app.IntensityTransformationTab, 'numeric');
            app.Para4EditField.ValueChangedFcn = createCallbackFcn(app, @Para4EditFieldValueChanged, true);
            app.SDFilteringTab = uitab(app.TabGroup);
            app.SDFilteringTab.Title = 'SD Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.SDFilteringTab);
            app.FilterTypeDropDownLabel.Visible = 'off';
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SDFilteringTab);
            app.FilterTypeDropDown.Items = {'Average', 'Median', 'Gaussian', 'Laplacian', 'Prewitt', 'Sobel', 'ContraHarm'};
            app.FilterTypeDropDown.Visible = 'off';
            app.FilterTypeDropDown.Value = 'Average';
            app.SDFilterButton = uibutton(app.SDFilteringTab, 'push');
            app.SDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SDFilterButtonPushed, true);
            app.SDFilterButton.Text = 'SD Filter';
            app.SmoothingPanel = uipanel(app.SDFilteringTab);
            app.SmoothingPanel.Title = 'Smoothing';
            app.AverageCheckBox = uicheckbox(app.SmoothingPanel);
            app.AverageCheckBox.Text = 'Average';
            app.MedianCheckBox = uicheckbox(app.SmoothingPanel);
            app.MedianCheckBox.Text = 'Median';
            app.GaussianCheckBox = uicheckbox(app.SmoothingPanel);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.ContraharmCheckBox = uicheckbox(app.SmoothingPanel);
            app.ContraharmCheckBox.Text = 'Contraharm';
            app.AverageMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask1.Value = 3;
            app.AverageMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask2.Value = 7;
            app.AverageMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask3.Value = 9;
            app.MedianMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask1.Value = 3;
            app.MedianMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask2.Value = 4;
            app.MedianMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask3.Value = 5;
            app.GaussianSigma1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma1.Value = 0.5;
            app.GaussianSigma2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma2.Value = 1;
            app.GaussianSigma3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma3.Value = 4;
            app.ContraharmMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask1.Value = 3;
            app.ContraharmMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask2.Value = 7;
            app.ContraharmMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask3.Value = 9;
            app.QEditFieldLabel = uilabel(app.SmoothingPanel);
            app.QEditFieldLabel.Text = 'Q';
            app.ContraharmQ = uieditfield(app.SmoothingPanel, 'numeric');
            app.MaskLabel = uilabel(app.SmoothingPanel);
            app.MaskLabel.Text = 'Mask';
            app.MaskLabel_2 = uilabel(app.SmoothingPanel);
            app.MaskLabel_2.Text = 'Mask';
            app.MaskLabel_3 = uilabel(app.SmoothingPanel);
            app.MaskLabel_3.Text = 'Mask';
            app.SigmaLabel = uilabel(app.SmoothingPanel);
            app.SigmaLabel.Text = 'Sigma';
            app.SharpeningPanel = uipanel(app.SDFilteringTab);
            app.SharpeningPanel.Title = 'Sharpening';
            app.LaplacianCheckBox = uicheckbox(app.SharpeningPanel);
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.PrewittCheckBox = uicheckbox(app.SharpeningPanel);
            app.PrewittCheckBox.Text = 'Prewitt';
            app.SobelCheckBox = uicheckbox(app.SharpeningPanel);
            app.SobelCheckBox.Text = 'Sobel';
            app.LaplacianAlpha1 = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha1.Value = 0.1;
            app.LaplacianAlpha2 = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha2.Value = 0.2;
            app.LaplacianAlpha3 = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha3.Value = 0.5;
            app.AlphaLabel = uilabel(app.SharpeningPanel);
            app.AlphaLabel.Text = 'Alpha';
            app.UIAxes12 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes13 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, '')
            ylabel(app.UIAxes13, '')
            app.UIAxes14 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, '')
            ylabel(app.UIAxes14, '')
            app.UIAxes15 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes15, 'Title')
            xlabel(app.UIAxes15, '')
            ylabel(app.UIAxes15, '')
            app.FDFilteringTab = uitab(app.TabGroup);
            app.FDFilteringTab.Title = 'FD Filtering';
            app.FilterTypeDropDown_2Label = uilabel(app.FDFilteringTab);
            app.FilterTypeDropDown_2Label.Visible = 'off';
            app.FilterTypeDropDown_2Label.Text = 'Filter Type';
            app.FilterTypeDropDown_2 = uidropdown(app.FDFilteringTab);
            app.FilterTypeDropDown_2.Items = {'IdealLowPass', 'GuassianLowPass', 'ButterworthLowPass', 'IdealHighPass', 'GuassianHighPass', 'ButterworthHighPass'};
            app.FilterTypeDropDown_2.Visible = 'off';
            app.FilterTypeDropDown_2.Value = 'IdealLowPass';
            app.FDFilterButton = uibutton(app.FDFilteringTab, 'push');
            app.FDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @FDFilterButtonPushed, true);
            app.FDFilterButton.Text = 'FD Filter';
            app.SmoothingPanel_2 = uipanel(app.FDFilteringTab);
            app.SmoothingPanel_2.Title = 'Smoothing';
            app.IdealLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.IdealLowPassCheckBox.Text = 'IdealLowPass';
            app.GaussianLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.GaussianLowPassCheckBox.Text = 'GaussianLowPass';
            app.ButterworthLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.ButterworthLowPassCheckBox.Text = 'ButterworthLowPass';
            app.D0EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.D0EditFieldLabel.Text = 'D0';
            app.IdealLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.IdealLowPassD0.Value = 30;
            app.D0EditField_2Label = uilabel(app.SmoothingPanel_2);
            app.D0EditField_2Label.Text = 'D0';
            app.GaussianLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.GaussianLowPassD0.Value = 30;
            app.D0EditField_3Label = uilabel(app.SmoothingPanel_2);
            app.D0EditField_3Label.Text = 'D0';
            app.ButterworthLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthLowPassD0.Value = 30;
            app.nEditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.nEditFieldLabel.Text = 'n';
            app.ButterworthLowPassn = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthLowPassn.Value = 2;
            app.SharpeningPanel_2 = uipanel(app.FDFilteringTab);
            app.SharpeningPanel_2.Title = 'Sharpening';
            app.IdealHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.IdealHighPassCheckBox.Text = 'IdealHighPass';
            app.GaussianHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.GaussianHighPassCheckBox.Text = 'GaussianHighPass';
            app.ButterworthHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.ButterworthHighPassCheckBox.Text = 'ButterworthHighPass';
            app.D0EditField_4Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_4Label.Text = 'D0';
            app.IdealHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.IdealHighPassD0.Value = 30;
            app.D0EditField_5Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_5Label.Text = 'D0';
            app.GaussianHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.GaussianHighPassD0.Value = 30;
            app.D0EditField_6Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_6Label.Text = 'D0';
            app.ButterworthHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.ButterworthHighPassD0.Value = 30;
            app.nEditField_2Label = uilabel(app.SharpeningPanel_2);
            app.nEditField_2Label.Text = 'n';
            app.ButterworthHighPassn = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.ButterworthHighPassn.Value = 2;
            app.UIAxes16 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16, 'Title')
            xlabel(app.UIAxes16, '')
            ylabel(app.UIAxes16, '')
            app.UIAxes16_2 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16_2, 'Title')
            xlabel(app.UIAxes16_2, '')
            ylabel(app.UIAxes16_2, '')
            app.UIAxes16_3 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16_3, 'Title')
            xlabel(app.UIAxes16_3, '')
            ylabel(app.UIAxes16_3, '')
            app.UIAxes16_4 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16_4, 'Title')
            xlabel(app.UIAxes16_4, '')
            ylabel(app.UIAxes16_4, '')
            app.NoiseReductionTab = uitab(app.TabGroup);
            app.NoiseReductionTab.Title = 'Noise Reduction';
            app.GrayRawImgAxis = uiaxes(app.UIFigure);
            title(app.GrayRawImgAxis, 'Original')
            xlabel(app.GrayRawImgAxis, '')
            ylabel(app.GrayRawImgAxis, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end